classdef appGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        FriesButton     matlab.ui.control.Button
        CheckoutButton  matlab.ui.control.Button
        HotDogButton    matlab.ui.control.Button
        BurgerButton    matlab.ui.control.Button
        OrderLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        ItemCount = 0 % Current Items in User Order
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BurgerButton
        function BurgerButtonPushed(app, event)
            if app.ItemCount == 2
                errordlg('You cannot add more than two items to your cart.','Woah There!');
            else
                if app.ItemCount == 0
                    app.OrderLabel.Text = append(app.OrderLabel.Text, 'Burger');
                    app.ItemCount = app.ItemCount + 1;
                else
                    if contains(app.OrderLabel.Text, 'Burger')
                        app.OrderLabel.Text = append(app.OrderLabel.Text, ' x2');
                    else
                        app.OrderLabel.Text = append(app.OrderLabel.Text, ', Burger');
                    end
                    app.ItemCount = app.ItemCount + 1;
                end
            end
            %app.OrderLabel.Text = test_func();
        end

        % Button pushed function: HotDogButton
        function HotDogButtonPushed(app, event)
            if app.ItemCount == 2
                errordlg('You cannot add more than two items to your cart.','Woah There!');
            else
                if app.ItemCount == 0
                    app.OrderLabel.Text = append(app.OrderLabel.Text, 'Hot Dog');
                    app.ItemCount = app.ItemCount + 1;
                else
                    if contains(app.OrderLabel.Text, 'Hot Dog')
                        app.OrderLabel.Text = append(app.OrderLabel.Text, ' x2');
                    else
                        app.OrderLabel.Text = append(app.OrderLabel.Text, ', Hot Dog');
                    end
                    app.ItemCount = app.ItemCount + 1;
                end
            end
            
        end

        % Button pushed function: FriesButton
        function FriesButtonPushed(app, event)
            if app.ItemCount == 2
                errordlg('You cannot add more than two items to your cart.','Woah There!');
            else
                if app.ItemCount == 0
                    app.OrderLabel.Text = append(app.OrderLabel.Text, 'Fries');
                    app.ItemCount = app.ItemCount + 1;
                else
                    if contains(app.OrderLabel.Text, 'Fries')
                        app.OrderLabel.Text = append(app.OrderLabel.Text, ' x2');
                    else
                        app.OrderLabel.Text = append(app.OrderLabel.Text, ', Fries');
                    end
                    app.ItemCount = app.ItemCount + 1;
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OrderLabel = uilabel(app.UIFigure);
            app.OrderLabel.Text = 'Order: ';
            app.BurgerButton = uibutton(app.UIFigure, 'push');
            app.BurgerButton.ButtonPushedFcn = createCallbackFcn(app, @BurgerButtonPushed, true);
            app.BurgerButton.Text = 'Burger';
            app.HotDogButton = uibutton(app.UIFigure, 'push');
            app.HotDogButton.ButtonPushedFcn = createCallbackFcn(app, @HotDogButtonPushed, true);
            app.HotDogButton.Text = 'Hot Dog';
            app.CheckoutButton = uibutton(app.UIFigure, 'push');
            app.CheckoutButton.Text = 'Checkout';
            app.FriesButton = uibutton(app.UIFigure, 'push');
            app.FriesButton.ButtonPushedFcn = createCallbackFcn(app, @FriesButtonPushed, true);
            app.FriesButton.Text = 'Fries';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end